classdef Skynet_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        RobotDropDownLabel        matlab.ui.control.Label
        RobotDropDown             matlab.ui.control.DropDown
        DrinksMenuButtonGroup     matlab.ui.container.ButtonGroup
        SpriteButton_2            matlab.ui.control.Button
        CokeButton                matlab.ui.control.Button
        VodkaButton               matlab.ui.control.Button
        RumButton                 matlab.ui.control.Button
        ResetEStopSwitch          matlab.ui.control.Switch
        EStopButton               matlab.ui.control.StateButton
        PositionEditFieldLabel    matlab.ui.control.Label
        PositionEditField         matlab.ui.control.NumericEditField
        PositionEditField_2Label  matlab.ui.control.Label
        PositionEditField_2       matlab.ui.control.NumericEditField
        PositionEditField_3Label  matlab.ui.control.Label
        PositionEditField_3       matlab.ui.control.NumericEditField
        PositionEditField_4Label  matlab.ui.control.Label
        PositionEditField_4       matlab.ui.control.NumericEditField
        PositionEditField_5Label  matlab.ui.control.Label
        PositionEditField_5       matlab.ui.control.NumericEditField
        PositionEditField_6Label  matlab.ui.control.Label
        PositionEditField_6       matlab.ui.control.NumericEditField
        ToDoImplementSafetyCiircuitLabel  matlab.ui.control.Label
        XEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.EditField
        YEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.EditField
        ZEditFieldLabel           matlab.ui.control.Label
        ZEditField                matlab.ui.control.EditField
        RollEditFieldLabel        matlab.ui.control.Label
        RollEditField             matlab.ui.control.EditField
        PitchEditFieldLabel       matlab.ui.control.Label
        PitchEditField            matlab.ui.control.EditField
        YawEditFieldLabel         matlab.ui.control.Label
        YawEditField              matlab.ui.control.EditField
        SetPositionButton         matlab.ui.control.Button
        HomeButton                matlab.ui.control.Button
        ClearButton               matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        ResetEStopButton          matlab.ui.control.Button
        CollisionAvoidanceExampleButton  matlab.ui.control.Button
        SensorInputExampleButton  matlab.ui.control.Button
        Joint1SliderLabel         matlab.ui.control.Label
        Joint1Slider              matlab.ui.control.Slider
        Joint2SliderLabel         matlab.ui.control.Label
        Joint2Slider              matlab.ui.control.Slider
        Joint3SliderLabel         matlab.ui.control.Label
        Joint3Slider              matlab.ui.control.Slider
        Joint4SliderLabel         matlab.ui.control.Label
        Joint4Slider              matlab.ui.control.Slider
        Joint5SliderLabel         matlab.ui.control.Label
        Joint5Slider              matlab.ui.control.Slider
        Joint6SliderLabel         matlab.ui.control.Label
        Joint6Slider              matlab.ui.control.Slider
        VisualServoingButton      matlab.ui.control.Button
    end


    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VodkaButton
        function VodkaButtonPushed(app, event)
            
            ur3 = evalin('base', 'ur3');
            kuka = evalin('base', 'kuka');
            Vodka_h = evalin('base', 'Vodka_h');
            vertices = evalin('base', 'vertices');
            transformedVertices = evalin('base', 'transformedVertices');
            Vodka(ur3,kuka,Vodka_h,vertices,transformedVertices);
         
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            kuka = evalin('base', 'kuka');
            changingValue = event.Value;
            %% need to figure out how to change this value into a 1,6 matrix
            %% Take all changing values into an array and use that as q1
            q1kuka = [ 0     0     0     0     0     0];
            qWaypoints = [q1kuka ...
            ; changingValue];
            qMatrix = InterpolateWaypointRadians(qWaypoints,deg2rad(5));

            for i = 1:size(qMatrix,1)
                kuka.model.animate(qMatrix(i,:));
    
                drawnow();
                pause(0.1);
            end
        end

        % Button pushed function: RumButton
        function RumButtonPushed(app, event)
            ur3 = evalin('base', 'ur3');
            kuka = evalin('base', 'kuka');
            Rum_h = evalin('base', 'Rum_h');
            vertices = evalin('base', 'vertices');
            transformedVertices = evalin('base', 'transformedVertices');
            Rum(ur3,kuka,Rum_h,vertices,transformedVertices);
        end

        % Button pushed function: CokeButton
        function CokeButtonPushed(app, event)
            ur3 = evalin('base', 'ur3');
            kuka = evalin('base', 'kuka');
            Coke_h = evalin('base', 'Coke_h');
            vertices = evalin('base', 'vertices');
            transformedVertices = evalin('base', 'transformedVertices');
            Coke(ur3,kuka,Coke_h,vertices,transformedVertices);
        end

        % Button pushed function: SpriteButton_2
        function SpriteButton_2Pushed(app, event)
            ur3 = evalin('base', 'ur3');
            kuka = evalin('base', 'kuka');
            Sprite_h = evalin('base', 'Sprite_h');
            vertices = evalin('base', 'vertices');
            transformedVertices = evalin('base', 'transformedVertices');
            Sprite(ur3,kuka,Sprite_h,vertices,transformedVertices);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            clf
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            clear all 
            clf
            clc
            IRAssignment2
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RobotDropDownLabel = uilabel(app.UIFigure);
            app.RobotDropDownLabel.Text = 'Robot';
            app.RobotDropDown = uidropdown(app.UIFigure);
            app.RobotDropDown.Items = {'KUKA', 'UR3'};
            app.RobotDropDown.Value = 'KUKA';
            app.DrinksMenuButtonGroup = uibuttongroup(app.UIFigure);
            app.DrinksMenuButtonGroup.Title = 'Drinks Menu';
            app.SpriteButton_2 = uibutton(app.DrinksMenuButtonGroup, 'push');
            app.SpriteButton_2.ButtonPushedFcn = createCallbackFcn(app, @SpriteButton_2Pushed, true);
            app.SpriteButton_2.Text = {'Sprite'; ''};
            app.CokeButton = uibutton(app.DrinksMenuButtonGroup, 'push');
            app.CokeButton.ButtonPushedFcn = createCallbackFcn(app, @CokeButtonPushed, true);
            app.CokeButton.Text = 'Coke';
            app.VodkaButton = uibutton(app.DrinksMenuButtonGroup, 'push');
            app.VodkaButton.ButtonPushedFcn = createCallbackFcn(app, @VodkaButtonPushed, true);
            app.VodkaButton.Text = 'Vodka';
            app.RumButton = uibutton(app.DrinksMenuButtonGroup, 'push');
            app.RumButton.ButtonPushedFcn = createCallbackFcn(app, @RumButtonPushed, true);
            app.RumButton.Text = {'Rum'; ''};
            app.ResetEStopSwitch = uiswitch(app.UIFigure, 'slider');
            app.EStopButton = uibutton(app.UIFigure, 'state');
            app.EStopButton.Text = 'E-Stop';
            app.PositionEditFieldLabel = uilabel(app.UIFigure);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.PositionEditField_2Label = uilabel(app.UIFigure);
            app.PositionEditField_2Label.Text = 'Position';
            app.PositionEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PositionEditField_3Label = uilabel(app.UIFigure);
            app.PositionEditField_3Label.Text = 'Position';
            app.PositionEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.PositionEditField_4Label = uilabel(app.UIFigure);
            app.PositionEditField_4Label.Text = 'Position';
            app.PositionEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.PositionEditField_5Label = uilabel(app.UIFigure);
            app.PositionEditField_5Label.Text = 'Position';
            app.PositionEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.PositionEditField_6Label = uilabel(app.UIFigure);
            app.PositionEditField_6Label.Text = 'Position';
            app.PositionEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.ToDoImplementSafetyCiircuitLabel = uilabel(app.UIFigure);
            app.ToDoImplementSafetyCiircuitLabel.Text = {'To Do - '; '- Implement Safety Ciircuit'; '- Look at implementing sensors'; 'and tracking output to a screen'; '-Use those sensors to trip or reset'; 'safery circuit and estop'};
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'text');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'text');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'text');
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.UIFigure, 'text');
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.UIFigure, 'text');
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.UIFigure, 'text');
            app.SetPositionButton = uibutton(app.UIFigure, 'push');
            app.SetPositionButton.Text = 'Set Position';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.Text = 'Home';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ResetEStopButton = uibutton(app.UIFigure, 'push');
            app.ResetEStopButton.Text = 'Reset E-Stop';
            app.CollisionAvoidanceExampleButton = uibutton(app.UIFigure, 'push');
            app.CollisionAvoidanceExampleButton.Text = 'Collision Avoidance Example';
            app.SensorInputExampleButton = uibutton(app.UIFigure, 'push');
            app.SensorInputExampleButton.Text = 'Sensor Input Example';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.MajorTicks = [];
            app.Joint1Slider.MajorTickLabels = {''};
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint1Slider.MinorTicks = [];
            app.Joint1Slider.Value = 50;
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.MajorTicks = [];
            app.Joint2Slider.MajorTickLabels = {''};
            app.Joint2Slider.MinorTicks = [];
            app.Joint2Slider.Value = 50;
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.MajorTicks = [];
            app.Joint3Slider.MajorTickLabels = {''};
            app.Joint3Slider.MinorTicks = [];
            app.Joint3Slider.Value = 50;
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.MajorTicks = [];
            app.Joint4Slider.MajorTickLabels = {''};
            app.Joint4Slider.MinorTicks = [];
            app.Joint4Slider.Value = 50;
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.MajorTicks = [];
            app.Joint5Slider.MajorTickLabels = {''};
            app.Joint5Slider.MinorTicks = [];
            app.Joint5Slider.Value = 50;
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.MajorTicks = [];
            app.Joint6Slider.MajorTickLabels = {''};
            app.Joint6Slider.MinorTicks = [];
            app.Joint6Slider.Value = 50;
            app.VisualServoingButton = uibutton(app.UIFigure, 'push');
            app.VisualServoingButton.Text = 'Visual Servoing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Skynet_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end